classdef vizGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        SelectCalibrationFileButton  matlab.ui.control.Button
        StartroomgenerationButton    matlab.ui.control.Button
        SelectImageFolderButton      matlab.ui.control.Button
        Box2SizeLabel                matlab.ui.control.Label
        Box2SizeTitle                matlab.ui.control.Label
        Box1SizeLabel                matlab.ui.control.Label
        Box1SizeTitle                matlab.ui.control.Label
        RotateDownButton             matlab.ui.control.Button
        RotateUpButton               matlab.ui.control.Button
        RotaterightButton            matlab.ui.control.Button
        RotateleftButton             matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        infoLabel                    matlab.ui.control.Label
        RoomFigure                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        selectedFolder = "";  % Description TODO: make empty string
        selectedCalibrationFile = ""
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RoomFigure.Visible = 'off'; 
            app.infoLabel.Visible = "on";
            app.infoLabel.Text = ['Please select Folder and Calibration',newline,'and start the Room generation!'];

        end

        % Button pushed function: RotateleftButton
        function RotateleftButtonPushed(app, event)
            % Get the current view
            [az, el] = view(app.RoomFigure);
            
            % add 10 degrees to the azimuth angle to rotate left
            az = az + 10;
            
            % Update the view
            view(app.RoomFigure, az, el);
        end

        % Button pushed function: RotaterightButton
        function RotaterightButtonPushed(app, event)
            % Get the current view
            [az, el] = view(app.RoomFigure);
            
            % subtract 10 degrees to the azimuth angle to rotate right
            az = az - 10;
            
            % Update the view
            view(app.RoomFigure, az, el);
        end

        % Button pushed function: RotateUpButton
        function RotateUpButtonPushed(app, event)
            % Get the current view
            [az, el] = view(app.RoomFigure);
            
            % Add 10 degrees to the elevation angle to rotate up
            el = el + 10;
            
            % Update the view
            view(app.RoomFigure, az, el);
        end

        % Button pushed function: RotateDownButton
        function RotateDownButtonPushed(app, event)
            % Get the current view
            [az, el] = view(app.RoomFigure);
            
            % Add 10 degrees to the elevation angle to rotate up
            el = el - 10;
            
            % Update the view
            view(app.RoomFigure, az, el);
        end

        % Button pushed function: SelectImageFolderButton
        function SelectImageFolderButtonPushed(app, event)
            selectedPath = uigetdir;  % Open the folder selection dialog
            
            if selectedPath ~= 0  % If a folder is selected
                app.selectedFolder = selectedPath;  % Save the selected folder path
                app.SelectImageFolderButton.BackgroundColor = [0,1,0];
            else
                % User clicked Cancel or closed the dialog box
            end
        end

        % Button pushed function: StartroomgenerationButton
        function StartroomgenerationButtonPushed(app, event)
            addpath('vizualization')
            display(app.selectedFolder)
            if app.selectedFolder ~= ""
                if app.selectedCalibrationFile ~= ""
                    app.infoLabel.Text = "Calculating coordinates...";
                    drawnow
                    % Read the JSON file
                    %jsonText = fileread('/Users/michael/cv_challenge/vizualization/testData/points3D_new4.json');
                    
                    %jsonText = fileread('/Users/michael/cv_challenge/vizualization/testData/3D_live_data.json');
                    % Decode the JSON text
                    %data = jsondecode(jsonText);
                    % Get the points array
                    %pointsArray = data.points;
                    
                    % Initialize PointsReconstructor object
                    reconstructor = PointsReconstructor(app.selectedFolder, app.selectedCalibrationFile, app);
                    %%
                    % Get the 3D points of the scene
                    pointsArray = double(reconstructor.get_3D_points());
             
                    
                    %save("test.mat","pointsArray")

                    app.infoLabel.Text = "Loading your room...";
                    drawnow
    
                    pointsArray = [pointsArray(:, 1), pointsArray(:, 3), -pointsArray(:, 2)];
    
                    size(pointsArray)
                    % CLear figure
                    cla(app.RoomFigure)
                    % Create Box objects for each cluster and add them to your Room:
                    room = Room(pointsArray, app);
                    app.infoLabel.Visible = "off";
                    drawnow
                    % Display the room in the UIAxes
                    room.show();
                    %room.showWallPlot(); % for debug 2D Walls
                    room.showWalls();
                    room.showWholePointcloud(room.fullPointCloud); % for
                    %debug 3d room
                    
                    app.RoomFigure.Visible = 'on'; 
                else
                    uialert(app.UIFigure, 'Please select a calibration file first.', 'No File Selected');
                end
            else
                uialert(app.UIFigure, 'Please select a folder with images first.', 'No Folder Selected');
            end
        end

        % Button pushed function: SelectCalibrationFileButton
        function SelectCalibrationFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select the calibration .txt file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path, file)]);
                app.selectedCalibrationFile = fullfile(path, file);
                app.SelectCalibrationFileButton.BackgroundColor = [0,1,0];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {597, 597};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RotateleftButton = uibutton(app.LeftPanel, 'push');
            app.RotateleftButton.ButtonPushedFcn = createCallbackFcn(app, @RotateleftButtonPushed, true);
            app.RotateleftButton.Text = 'Rotate left';
            app.RotaterightButton = uibutton(app.LeftPanel, 'push');
            app.RotaterightButton.ButtonPushedFcn = createCallbackFcn(app, @RotaterightButtonPushed, true);
            app.RotaterightButton.Text = 'Rotate right';
            app.RotateUpButton = uibutton(app.LeftPanel, 'push');
            app.RotateUpButton.ButtonPushedFcn = createCallbackFcn(app, @RotateUpButtonPushed, true);
            app.RotateUpButton.Text = 'Rotate Up';
            app.RotateDownButton = uibutton(app.LeftPanel, 'push');
            app.RotateDownButton.ButtonPushedFcn = createCallbackFcn(app, @RotateDownButtonPushed, true);
            app.RotateDownButton.Text = 'Rotate Down';
            app.Box1SizeTitle = uilabel(app.LeftPanel);
            app.Box1SizeTitle.Text = 'Box 1 Maße';
            app.Box1SizeLabel = uilabel(app.LeftPanel);
            app.Box1SizeLabel.Text = 'Select first Box';
            app.Box2SizeTitle = uilabel(app.LeftPanel);
            app.Box2SizeTitle.Text = 'Box 2 Maße';
            app.Box2SizeLabel = uilabel(app.LeftPanel);
            app.Box2SizeLabel.Text = '';
            app.SelectImageFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageFolderButtonPushed, true);
            app.SelectImageFolderButton.Text = 'Select Image Folder';
            app.StartroomgenerationButton = uibutton(app.LeftPanel, 'push');
            app.StartroomgenerationButton.ButtonPushedFcn = createCallbackFcn(app, @StartroomgenerationButtonPushed, true);
            app.StartroomgenerationButton.Text = 'Start room generation';
            app.SelectCalibrationFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectCalibrationFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCalibrationFileButtonPushed, true);
            app.SelectCalibrationFileButton.Text = 'Select Calibration File';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'title.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.RoomFigure = uiaxes(app.RightPanel);
            title(app.RoomFigure, 'Your Room')
            xlabel(app.RoomFigure, 'X')
            ylabel(app.RoomFigure, 'Y')
            zlabel(app.RoomFigure, 'Z')
            app.infoLabel = uilabel(app.RightPanel);
            app.infoLabel.Text = {'sdsdsdsdsdsdsdsdsdsd'; 'Label'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vizGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end